classdef outliers_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes_signal_3  matlab.ui.control.UIAxes
        UIAxes_signal_2  matlab.ui.control.UIAxes
        UIAxes_img       matlab.ui.control.UIAxes
        UIAxes_signal    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgs
        values
        bin_outliers
        values2
        bin_outliers2
        values3
        bin_outliers3
        x
        selected
        line
        points
        point_selected
        points_red
        line2
        points2
        point_selected2
        points_red2
        line3
        points3
        point_selected3
        points_red3
    end
    
    methods (Access = private)
        
        
        function redraw(app)

            delete(app.point_selected)
            if app.bin_outliers(app.selected)
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'ro');
            else
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'bo');
            end

            delete(app.point_selected2)
            if app.bin_outliers2(app.selected)
                app.point_selected2 = plot(app.UIAxes_signal_2,app.x(app.selected),app.values2(app.selected),'ro');
            else
                app.point_selected2 = plot(app.UIAxes_signal_2,app.x(app.selected),app.values2(app.selected),'bo');
            end


            delete(app.point_selected3)
            if app.bin_outliers3(app.selected)
                app.point_selected3 = plot(app.UIAxes_signal_3,app.x(app.selected),app.values3(app.selected),'ro');
            else
                app.point_selected3 = plot(app.UIAxes_signal_3,app.x(app.selected),app.values3(app.selected),'bo');
            end


            hold(app.UIAxes_img, 'off')
            imshow(app.imgs(:,:,app.selected),'Parent',app.UIAxes_img);
            title(app.UIAxes_img,[num2str(app.selected) '/' num2str(length(app.values))])


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgs, values, bin_outliers, name, values2, bin_outliers2, name2, values3, bin_outliers3, name3)
            
            app.imgs = imgs;
            app.values = values;
            app.bin_outliers = bin_outliers;

            app.values2 = values2;
            app.bin_outliers2 = bin_outliers2;

            app.values3 = values3;
            app.bin_outliers3 = bin_outliers3;

            app.x = 1:length(values);

            app.selected = 1;

            hold(app.UIAxes_img, 'off')
            imshow(app.imgs(:,:,app.selected),'Parent',app.UIAxes_img);
            title(app.UIAxes_img,[num2str(app.selected) '/' num2str(length(app.values))])


            hold(app.UIAxes_signal, 'on')
            title(app.UIAxes_signal,name)
            app.line = plot(app.UIAxes_signal,app.x,app.values,'b-');
            app.points = plot(app.UIAxes_signal,app.x,app.values,'b.');
            app.points_red = plot(app.UIAxes_signal,app.x(app.bin_outliers),app.values(app.bin_outliers),'r.',MarkerSize=8);
            if app.bin_outliers(app.selected)
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'ro');
            else
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'bo');
            end


            hold(app.UIAxes_signal_2, 'on')
            title(app.UIAxes_signal_2,name2)
            app.line2 = plot(app.UIAxes_signal_2,app.x,app.values2,'b-');
            app.points2 = plot(app.UIAxes_signal_2,app.x,app.values2,'b.');
            app.points_red2 = plot(app.UIAxes_signal_2,app.x(app.bin_outliers2),app.values2(app.bin_outliers2),'r.',MarkerSize=8);
            if app.bin_outliers2(app.selected)
                app.point_selected2 = plot(app.UIAxes_signal_2,app.x(app.selected),app.values2(app.selected),'ro');
            else
                app.point_selected2 = plot(app.UIAxes_signal_2,app.x(app.selected),app.values2(app.selected),'bo');
            end

            
            hold(app.UIAxes_signal_3, 'on')
            title(app.UIAxes_signal_3,name3)
            app.line3 = plot(app.UIAxes_signal_3,app.x,app.values3,'b-');
            app.points3 = plot(app.UIAxes_signal_3,app.x,app.values3,'b.');
            app.points_red3 = plot(app.UIAxes_signal_3,app.x(app.bin_outliers3),app.values3(app.bin_outliers3),'r.',MarkerSize=8);
            if app.bin_outliers3(app.selected)
                app.point_selected3 = plot(app.UIAxes_signal_3,app.x(app.selected),app.values3(app.selected),'ro');
            else
                app.point_selected3 = plot(app.UIAxes_signal_3,app.x(app.selected),app.values3(app.selected),'bo');
            end

        end

        % Button down function: UIAxes_signal
        function UIAxes_signalButtonDown(app, event)
            
        end

        % Callback function: UIAxes_img, UIFigure
        function UIFigureWindowButtonDown(app, event)
            try 
                if isequal(event.Source.CurrentObject.Parent,app.UIAxes_signal) || isequal(event.Source.CurrentObject.Parent,app.UIAxes_signal_2)|| isequal(event.Source.CurrentObject.Parent,app.UIAxes_signal_3)
                    object_x = event.Source.CurrentObject.Parent;
                else
                    return
                end
            catch
                return
            end
            
                
             position=object_x.CurrentPoint;
             position=[position(1,1),position(1,2)];

             app.selected = round(position(1)); 
             if app.selected <= 0
                 app.selected = 1;
             end
             if app.selected > length(app.values)
                 app.selected = length(app.values);
             end
             app.redraw();


        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
                app.selected = app.selected - 1;
            end
            if strcmp(key,'rightarrow')
                app.selected = app.selected + 1;
            end
            if app.selected <= 0
                app.selected = 1;
            end
            if app.selected > length(app.values)
                app.selected = length(app.values);
            end
            app.redraw();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes_signal = uiaxes(app.UIFigure);
            title(app.UIAxes_signal, 'Title')
            xlabel(app.UIAxes_signal, 'X')
            ylabel(app.UIAxes_signal, 'Y')
            zlabel(app.UIAxes_signal, 'Z')
            app.UIAxes_signal.ButtonDownFcn = createCallbackFcn(app, @UIAxes_signalButtonDown, true);
            app.UIAxes_img = uiaxes(app.UIFigure);
            title(app.UIAxes_img, 'Title')
            xlabel(app.UIAxes_img, 'X')
            ylabel(app.UIAxes_img, 'Y')
            zlabel(app.UIAxes_img, 'Z')
            app.UIAxes_img.ButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes_signal_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_signal_2, 'Title')
            xlabel(app.UIAxes_signal_2, 'X')
            ylabel(app.UIAxes_signal_2, 'Y')
            zlabel(app.UIAxes_signal_2, 'Z')
            app.UIAxes_signal_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_signal_3, 'Title')
            xlabel(app.UIAxes_signal_3, 'X')
            ylabel(app.UIAxes_signal_3, 'Y')
            zlabel(app.UIAxes_signal_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outliers_analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end